classdef RobotControlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        CenterPanel                 matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        AddWarningSign              matlab.ui.control.Button
        HandforwardButton           matlab.ui.control.Button
        HandbackButton              matlab.ui.control.Button
        YawEditField                matlab.ui.control.NumericEditField
        YawEditFieldLabel           matlab.ui.control.Label
        PitchEditField              matlab.ui.control.NumericEditField
        PitchEditFieldLabel         matlab.ui.control.Label
        RollEditField               matlab.ui.control.NumericEditField
        RollEditFieldLabel          matlab.ui.control.Label
        ZSpinner                    matlab.ui.control.Spinner
        ZSpinnerLabel               matlab.ui.control.Label
        YSpinner                    matlab.ui.control.Spinner
        YSpinnerLabel               matlab.ui.control.Label
        XSpinner                    matlab.ui.control.Spinner
        XSpinnerLabel               matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
        ResetButton                 matlab.ui.control.Button
        EmergencyStopButton         matlab.ui.control.StateButton
        LoadButton                  matlab.ui.control.Button
        Link6EditField              matlab.ui.control.NumericEditField
        Link4EditField              matlab.ui.control.NumericEditField
        Link3EditField              matlab.ui.control.NumericEditField
        Link2EditField              matlab.ui.control.NumericEditField
        Link1EditField              matlab.ui.control.NumericEditField
        EndEffectorSlider           matlab.ui.control.Slider
        EndEffectorSliderLabel      matlab.ui.control.Label
        ForeArmSlider               matlab.ui.control.Slider
        ForearmLabel                matlab.ui.control.Label
        RearArmSlider               matlab.ui.control.Slider
        RearArmSliderLabel          matlab.ui.control.Label
        BaseSlider                  matlab.ui.control.Slider
        BaseSliderLabel             matlab.ui.control.Label
        LinearRailSlider            matlab.ui.control.Slider
        LinearRailSliderLabel       matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        ofSugarCubesEditField       matlab.ui.control.NumericEditField
        ofSugarCubesEditFieldLabel  matlab.ui.control.Label
        CustomiseYourOrderLabel     matlab.ui.control.Label
        SelectYourMilkButtonGroup   matlab.ui.container.ButtonGroup
        NoneButton                  matlab.ui.control.RadioButton
        SkimButton                  matlab.ui.control.RadioButton
        FullCreamButton             matlab.ui.control.RadioButton
        PlaceOrderButton            matlab.ui.control.Button
        SelectYourTeaButtonGroup    matlab.ui.container.ButtonGroup
        LemonGingerButton           matlab.ui.control.RadioButton
        GreenTeaButton              matlab.ui.control.RadioButton
        EnglishBreakfastButton      matlab.ui.control.RadioButton
        Lamp                        matlab.ui.control.Lamp
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        obj % The starter class
        modelDobot % The loaded Dobot
        modelCalcDobot
        qz = [0     0     deg2rad(5)     deg2rad(20)     0     0];
        qr = [0	    pi/2  -pi/2	        0	             0	   0];
    end
    
    methods (Access = private)
        
        function EnableSliders(app, bool)
            app.LinearRailSlider.Enable = bool;
            app.BaseSlider.Enable = bool;
            app.RearArmSlider.Enable = bool;
            app.ForeArmSlider.Enable = bool;
            app.EndEffectorSlider.Enable = bool;

            app.Link1EditField.Enable = bool;
            app.Link2EditField.Enable = bool;
            app.Link3EditField.Enable = bool;
            app.Link4EditField.Enable = bool;
            app.Link6EditField.Enable = bool;
        end
        
        function EnableCartesianButtons(app, bool)
            app.XSpinner.Enable = bool;
            app.YSpinner.Enable = bool;
            app.ZSpinner.Enable = bool;
            app.RollEditField.Enable = bool;
            app.PitchEditField.Enable = bool;
            app.YawEditField.Enable = bool;
        end

        function MoveRpy(app, dimension, value)
            q = app.modelDobot.model.getpos;
            tr = app.modelDobot.model.fkine(q);
            rpy = tr2rpy(tr);
            switch dimension
                case 'r'
                    a = 1;
                case 'p'
                    a = 2;
                case 'y'
                    a = 3;
                otherwise
                    disp("Invalid dimension requested - roll used as default");
                    a = 1;
            end
            rpy(1,a) = deg2rad(value);
            tr = rpy2tr(rpy);
            tempQ = q;
            tempQ = app.modelDobot.model.ikcon(tr,tempQ);
            newQ(1,1:5) = q(1,1:5);
            newQ(1,6) = tempQ(1,6);
            app.modelDobot.model.animate(newQ);
            SetLinkValues(app, newQ);
        end
        
        function MoveCartesian(app, dimension, value)
            switch dimension
                case 'x'
                    a = 1;
                case 'y'
                    a = 2;
                case 'z'
                    a = 3;
                otherwise
                    disp("Invalid dimension requested - x used as default");
                    a = 1;
            end
            q = app.modelDobot.model.getpos;
            tr = app.modelCalcDobot.model.fkine(q(1,1:4));
            tr(a,4) = value;
            q = app.modelCalcDobot.model.ikcon(tr,q(1,1:4));
            newQ = app.obj.CalcDobotTo6Dof(q,0);
            app.modelDobot.model.animate(newQ);
            SetLinkValues(app, newQ);
        end
        
        function MoveLink(app, linkNumber, value)
            q = app.modelDobot.model.getpos;
            newQ = q;
            if linkNumber == 6
                newQ(1,linkNumber) = deg2rad(value);
            else 
                if linkNumber == 1
                   newQ(1,linkNumber) = value;
                else
                    newQ(1,linkNumber) = deg2rad(value);
                end
                newQ = app.obj.CalcDobotTo6Dof(newQ(1,1:4),0);
                newQ(1,6) = q(1,6);
            end
            app.modelDobot.model.animate(newQ);
            tr = app.modelDobot.model.fkine(newQ);   
            SetCartesianValues(app, tr)
        end
        
        function InitialiseFields(app)
            q = app.modelDobot.model.getpos;
            SetLinkValues(app, q);
            tr = app.modelDobot.model.fkine(q);      
            SetCartesianValues(app, tr);
        end
        
        function SetLinkValues(app, q)
            app.Link1EditField.Value = q(1,1); %remove
            app.Link2EditField.Value = rad2deg(q(1,2));
            app.Link3EditField.Value = rad2deg(q(1,3));
            app.Link4EditField.Value = rad2deg(q(1,4));
            app.Link6EditField.Value = rad2deg(q(1,6));

            app.LinearRailSlider.Value = q(1,1);
            app.BaseSlider.Value = rad2deg(q(1,2));
            app.RearArmSlider.Value = rad2deg(q(1,3));
            app.ForeArmSlider.Value = rad2deg(q(1,4));
            app.EndEffectorSlider.Value = rad2deg(q(1,6));
        end
        
        function SetCartesianValues(app, tr) %Change to setSpinner values?
            app.XSpinner.Value = tr(1,4);
            app.YSpinner.Value = tr(2,4);
            app.ZSpinner.Value = tr(3,4);

            rpy = tr2rpy(tr,'deg');
            app.RollEditField.Value = rpy(1,1);
            app.PitchEditField.Value = rpy(1,2);
            app.YawEditField.Value = rpy(1,3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.obj = Assignment2Starter(false);            
            app.modelDobot = app.obj.robot;                                 %% Remove and replace with app.obj.robot?
            app.modelCalcDobot = app.obj.calcDobot;

            % Turn on lamp
            app.Lamp.Color = 'green';

            % Turn off load button and enable the move buttons and sliders
            app.EmergencyStopButton.Enable = true;
            app.LoadButton.Enable = false;
            app.PlaceOrderButton.Enable = true;
            app.HandforwardButton.Enable = true;
            app.AddWarningSign.Enable = true;
            EnableCartesianButtons(app,true);
            EnableSliders(app,true);

            % Initialise values
            InitialiseFields(app);
        end

        % Value changed function: EmergencyStopButton
        function EmergencyStopButtonValueChanged(app, event)
            value = app.EmergencyStopButton.Value;
            if value
                app.Switch.Value = '';
                app.Lamp.Color = 'red';
                app.PlaceOrderButton.Enable = false;
                EnableCartesianButtons(app,false);
                EnableSliders(app,false);
                app.obj.h = value;
            else 
                app.Switch.Enable = true;
            end
        end

        % Callback function
        function ReengageSwitchValueChanged(app, event)
            %value = app.ReengageSwitch.Value;
            app.Lamp.Color = 'green';
            app.PlaceOrderButton.Enable = true;
            EnableCartesianButtons(app,true);
            EnableSliders(app,true);
            app.ReengageSwitch.Enable = false;
            app.obj.h = false;
        end

        % Value changed function: Link1EditField
        function Link1EditFieldValueChanged(app, event)
            value = app.Link1EditField.Value;
            app.LinearRailSlider.Value = value;
            MoveLink(app, 1, value);
        end

        % Value changed function: Link2EditField
        function Link2EditFieldValueChanged(app, event)
            value = app.Link2EditField.Value;
            app.BaseSlider.Value = value;
            MoveLink(app, 2, value);
        end

        % Value changed function: Link3EditField
        function Link3EditFieldValueChanged(app, event)
            value = app.Link3EditField.Value;
            app.RearArmSlider.Value = value;
            MoveLink(app, 3, value);            
        end

        % Value changed function: Link4EditField
        function Link4EditFieldValueChanged(app, event)
            value = app.Link4EditField.Value;
            app.ForeArmSlider.Value = value;
            MoveLink(app, 4, value);
        end

        % Value changed function: Link6EditField
        function Link6EditFieldValueChanged(app, event)
            value = app.Link6EditField.Value;
            app.EndEffectorSlider.Value = value;
            MoveLink(app, 6, value);
        end

        % Value changed function: LinearRailSlider
        function LinearRailSliderValueChanged(app, event)
            value = app.LinearRailSlider.Value;
            app.Link1EditField.Value = value;
            MoveLink(app, 1, value);
        end

        % Value changed function: BaseSlider
        function BaseSliderValueChanged(app, event)
            value = app.BaseSlider.Value;
            app.Link2EditField.Value = value;
            MoveLink(app, 2, value);
        end

        % Value changed function: RearArmSlider
        function RearArmSliderValueChanged(app, event)
            value = app.RearArmSlider.Value;
            app.Link3EditField.Value = value;
            MoveLink(app, 3, value);
        end

        % Value changed function: ForeArmSlider
        function ForeArmSliderValueChanged(app, event)
            value = app.ForeArmSlider.Value;
            app.Link4EditField.Value = value;
            MoveLink(app, 4, value);
        end

        % Value changed function: EndEffectorSlider
        function EndEffectorSliderValueChanged(app, event)
            value = app.EndEffectorSlider.Value;
            app.Link6EditField.Value = value;
            MoveLink(app, 6, value);
        end

        % Value changing function: LinearRailSlider
        function LinearRailSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link1EditField.Value = changingValue;
            MoveLink(app, 1, changingValue);
        end

        % Value changing function: BaseSlider
        function BaseSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link2EditField.Value = changingValue;
            MoveLink(app, 2, changingValue);
        end

        % Value changing function: RearArmSlider
        function RearArmSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link3EditField.Value = changingValue;
            MoveLink(app, 3, changingValue);
        end

        % Value changing function: ForeArmSlider
        function ForeArmSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link4EditField.Value = changingValue;
            MoveLink(app, 4, changingValue);
        end

        % Value changing function: EndEffectorSlider
        function EndEffectorSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link6EditField.Value = changingValue;
            MoveLink(app, 6, changingValue);
        end

        % Button pushed function: PlaceOrderButton
        function PlaceOrderButtonPushed(app, event)
            EnableCartesianButtons(app,false);
            EnableSliders(app,false);
            app.ResetButton.Enable = false;
            app.AddWarningSign.Enable = false;
            switch app.SelectYourTeaButtonGroup.SelectedObject
                case app.EnglishBreakfastButton
                    selectedTea = 1;
                case app.GreenTeaButton
                    selectedTea = 2;
                case app.LemonGingerButton
                    selectedTea = 3;
            end
            switch app.SelectYourMilkButtonGroup.SelectedObject
                case app.NoneButton
                    selectedMilk = 0;
                case app.FullCreamButton
                    selectedMilk = 1;
                case app.SkimButton
                    selectedMilk = 2;
            end 
            sugarQuantity = app.ofSugarCubesEditField.Value;
            app.obj.MakeTea(selectedTea,selectedMilk,sugarQuantity);
            app.ResetButton.Enable = true;
            app.AddWarningSign.Enable = true;
            EnableSliders(app,true);
            EnableCartesianButtons(app,true);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.PlaceOrderButton.Enable = false;
            EnableSliders(app,false);
            EnableCartesianButtons(app,false);

            app.obj.ResetSimulation();
            
            app.PlaceOrderButton.Enable = true;
            EnableSliders(app,true);
            EnableCartesianButtons(app,true);
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            value = app.ZSpinner.Value;
            MoveCartesian(app,'z',value);
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            value = app.YSpinner.Value;
            MoveCartesian(app,'y',value);
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            value = app.XSpinner.Value;
            MoveCartesian(app,'x',value);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            %value = app.Switch.Value;
            app.Lamp.Color = 'green';
            app.PlaceOrderButton.Enable = true;
            EnableCartesianButtons(app,true);
            EnableSliders(app,true);
            app.Switch.Enable = false;
            app.obj.h = false;
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            value = app.RollEditField.Value;
            MoveRpy(app, 'r', value);
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            value = app.PitchEditField.Value;
            MoveRpy(app, 'p', value);
        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            value = app.YawEditField.Value;
            MoveRpy(app, 'y', value);
        end

        % Button pushed function: HandforwardButton
        function HandforwardButtonPushed(app, event)
            value = app.obj.PlaceHand([-0.6,-1,1.55]);
            if value
                app.Lamp.Color = 'red';
            else 
                app.Lamp.Color = 'green';
            end
            app.HandbackButton.Enable = true;
            app.HandforwardButton.Enable = false;
        end

        % Button pushed function: HandbackButton
        function HandbackButtonPushed(app, event)
            app.obj.RemoveHand();
            app.Lamp.Color = 'green';
            app.HandforwardButton.Enable = true;
            app.HandbackButton.Enable = false;
        end

        % Button pushed function: AddWarningSign
        function AddWarningSignPushed(app, event)
            app.PlaceOrderButton.Enable = false;
            EnableSliders(app,false);
            EnableCartesianButtons(app,false);
            app.HandforwardButton.Enable = false;
            app.ResetButton.Enable = false;

            app.obj.DemoVisualServoing();

            app.PlaceOrderButton.Enable = true;
            EnableSliders(app,true);
            EnableCartesianButtons(app,true);
            app.HandforwardButton.Enable = true;
            app.ResetButton.Enable = true;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x', 228};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.LinearRailSliderLabel = uilabel(app.GridLayout2);
            app.LinearRailSliderLabel.Text = 'Linear Rail';
            app.LinearRailSlider = uislider(app.GridLayout2);
            app.LinearRailSlider.Limits = [-1.33 0];
            app.LinearRailSlider.ValueChangedFcn = createCallbackFcn(app, @LinearRailSliderValueChanged, true);
            app.LinearRailSlider.ValueChangingFcn = createCallbackFcn(app, @LinearRailSliderValueChanging, true);
            app.LinearRailSlider.Enable = 'off';
            app.BaseSliderLabel = uilabel(app.GridLayout2);
            app.BaseSliderLabel.Text = 'Base';
            app.BaseSlider = uislider(app.GridLayout2);
            app.BaseSlider.Limits = [-135 135];
            app.BaseSlider.ValueChangedFcn = createCallbackFcn(app, @BaseSliderValueChanged, true);
            app.BaseSlider.ValueChangingFcn = createCallbackFcn(app, @BaseSliderValueChanging, true);
            app.BaseSlider.Enable = 'off';
            app.RearArmSliderLabel = uilabel(app.GridLayout2);
            app.RearArmSliderLabel.Text = 'Rear Arm';
            app.RearArmSlider = uislider(app.GridLayout2);
            app.RearArmSlider.Limits = [5 80];
            app.RearArmSlider.ValueChangedFcn = createCallbackFcn(app, @RearArmSliderValueChanged, true);
            app.RearArmSlider.ValueChangingFcn = createCallbackFcn(app, @RearArmSliderValueChanging, true);
            app.RearArmSlider.Enable = 'off';
            app.RearArmSlider.Value = 5;
            app.ForearmLabel = uilabel(app.GridLayout2);
            app.ForearmLabel.Text = 'Fore Arm';
            app.ForeArmSlider = uislider(app.GridLayout2);
            app.ForeArmSlider.Limits = [-5 85];
            app.ForeArmSlider.ValueChangedFcn = createCallbackFcn(app, @ForeArmSliderValueChanged, true);
            app.ForeArmSlider.ValueChangingFcn = createCallbackFcn(app, @ForeArmSliderValueChanging, true);
            app.ForeArmSlider.Enable = 'off';
            app.ForeArmSlider.Value = 15;
            app.EndEffectorSliderLabel = uilabel(app.GridLayout2);
            app.EndEffectorSliderLabel.Text = 'End Effector';
            app.EndEffectorSlider = uislider(app.GridLayout2);
            app.EndEffectorSlider.Limits = [-85 85];
            app.EndEffectorSlider.MajorTicks = [-85 -51 -17 0 17 51 85];
            app.EndEffectorSlider.ValueChangedFcn = createCallbackFcn(app, @EndEffectorSliderValueChanged, true);
            app.EndEffectorSlider.ValueChangingFcn = createCallbackFcn(app, @EndEffectorSliderValueChanging, true);
            app.EndEffectorSlider.Enable = 'off';
            app.Link1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Link1EditField.Limits = [-1.33 0];
            app.Link1EditField.ValueDisplayFormat = '%.2f';
            app.Link1EditField.ValueChangedFcn = createCallbackFcn(app, @Link1EditFieldValueChanged, true);
            app.Link1EditField.Enable = 'off';
            app.Link2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Link2EditField.Limits = [-135 135];
            app.Link2EditField.ValueDisplayFormat = '%.2f';
            app.Link2EditField.ValueChangedFcn = createCallbackFcn(app, @Link2EditFieldValueChanged, true);
            app.Link2EditField.Enable = 'off';
            app.Link3EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Link3EditField.Limits = [5 80];
            app.Link3EditField.ValueDisplayFormat = '%.2f';
            app.Link3EditField.ValueChangedFcn = createCallbackFcn(app, @Link3EditFieldValueChanged, true);
            app.Link3EditField.Enable = 'off';
            app.Link3EditField.Value = 5;
            app.Link4EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Link4EditField.Limits = [-5 85];
            app.Link4EditField.ValueDisplayFormat = '%.2f';
            app.Link4EditField.ValueChangedFcn = createCallbackFcn(app, @Link4EditFieldValueChanged, true);
            app.Link4EditField.Enable = 'off';
            app.Link4EditField.Value = 15;
            app.Link6EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Link6EditField.Limits = [-85 85];
            app.Link6EditField.ValueDisplayFormat = '%.2f';
            app.Link6EditField.ValueChangedFcn = createCallbackFcn(app, @Link6EditFieldValueChanged, true);
            app.Link6EditField.Enable = 'off';
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.EmergencyStopButton = uibutton(app.GridLayout2, 'state');
            app.EmergencyStopButton.ValueChangedFcn = createCallbackFcn(app, @EmergencyStopButtonValueChanged, true);
            app.EmergencyStopButton.Enable = 'off';
            app.EmergencyStopButton.Icon = 'stop_button.jpg';
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Switch = uiswitch(app.GridLayout2, 'slider');
            app.Switch.Items = {'', 'Resume'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.Switch.Value = '';
            app.XSpinnerLabel = uilabel(app.GridLayout2);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.GridLayout2);
            app.XSpinner.Step = 0.01;
            app.XSpinner.ValueDisplayFormat = '%.3f';
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.XSpinner.Enable = 'off';
            app.YSpinnerLabel = uilabel(app.GridLayout2);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.GridLayout2);
            app.YSpinner.Step = 0.01;
            app.YSpinner.ValueDisplayFormat = '%.3f';
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.YSpinner.Enable = 'off';
            app.ZSpinnerLabel = uilabel(app.GridLayout2);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.GridLayout2);
            app.ZSpinner.Step = 0.01;
            app.ZSpinner.ValueDisplayFormat = '%.3f';
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.ZSpinner.Enable = 'off';
            app.RollEditFieldLabel = uilabel(app.GridLayout2);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RollEditField.ValueDisplayFormat = '%.3f';
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.RollEditField.Editable = 'off';
            app.RollEditField.Enable = 'off';
            app.PitchEditFieldLabel = uilabel(app.GridLayout2);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PitchEditField.ValueDisplayFormat = '%.3f';
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.PitchEditField.Editable = 'off';
            app.PitchEditField.Enable = 'off';
            app.YawEditFieldLabel = uilabel(app.GridLayout2);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.GridLayout2, 'numeric');
            app.YawEditField.ValueDisplayFormat = '%.3f';
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.YawEditField.Editable = 'off';
            app.YawEditField.Enable = 'off';
            app.HandbackButton = uibutton(app.GridLayout2, 'push');
            app.HandbackButton.ButtonPushedFcn = createCallbackFcn(app, @HandbackButtonPushed, true);
            app.HandbackButton.Enable = 'off';
            app.HandbackButton.Text = 'Hand back';
            app.HandforwardButton = uibutton(app.GridLayout2, 'push');
            app.HandforwardButton.ButtonPushedFcn = createCallbackFcn(app, @HandforwardButtonPushed, true);
            app.HandforwardButton.Enable = 'off';
            app.HandforwardButton.Text = 'Hand forward';
            app.AddWarningSign = uibutton(app.GridLayout2, 'push');
            app.AddWarningSign.ButtonPushedFcn = createCallbackFcn(app, @AddWarningSignPushed, true);
            app.AddWarningSign.Enable = 'off';
            app.AddWarningSign.Text = 'Add Warning Sign';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.Lamp = uilamp(app.GridLayout4);
            app.SelectYourTeaButtonGroup = uibuttongroup(app.GridLayout4);
            app.SelectYourTeaButtonGroup.Title = 'Select Your Tea';
            app.EnglishBreakfastButton = uiradiobutton(app.SelectYourTeaButtonGroup);
            app.EnglishBreakfastButton.Text = 'English Breakfast';
            app.EnglishBreakfastButton.Value = true;
            app.GreenTeaButton = uiradiobutton(app.SelectYourTeaButtonGroup);
            app.GreenTeaButton.Text = 'Green Tea';
            app.LemonGingerButton = uiradiobutton(app.SelectYourTeaButtonGroup);
            app.LemonGingerButton.Text = 'Lemon & Ginger';
            app.PlaceOrderButton = uibutton(app.GridLayout4, 'push');
            app.PlaceOrderButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceOrderButtonPushed, true);
            app.PlaceOrderButton.Enable = 'off';
            app.PlaceOrderButton.Text = 'Place Order';
            app.SelectYourMilkButtonGroup = uibuttongroup(app.GridLayout4);
            app.SelectYourMilkButtonGroup.Title = 'Select Your Milk';
            app.FullCreamButton = uiradiobutton(app.SelectYourMilkButtonGroup);
            app.FullCreamButton.Text = 'Full Cream';
            app.SkimButton = uiradiobutton(app.SelectYourMilkButtonGroup);
            app.SkimButton.Text = 'Skim';
            app.NoneButton = uiradiobutton(app.SelectYourMilkButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.CustomiseYourOrderLabel = uilabel(app.GridLayout4);
            app.CustomiseYourOrderLabel.Text = 'Customise Your Order';
            app.ofSugarCubesEditFieldLabel = uilabel(app.GridLayout4);
            app.ofSugarCubesEditFieldLabel.Text = '# of Sugar Cubes';
            app.ofSugarCubesEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ofSugarCubesEditField.Limits = [0 10];
            app.ofSugarCubesEditField.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControlApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end